classdef imageMorphing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes_3             matlab.ui.control.UIAxes
        UploadImageButton    matlab.ui.control.Button
        UploadImageButton_2  matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mousePos)
           [x,y] = ginput;


        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            image = uigetfile({'*.tif; *.jpg; *.jpeg','Image (*.tif, *.jpg, *.jpeg)'},'Select an image');
            %axes(handles.axes1);
            imshow(image,'Parent',app.UIAxes);
        end

        % Button pushed function: UploadImageButton_2
        function UploadImageButton_2Pushed(app, event)
            image = uigetfile({'*.tif; *.jpg; *.jpeg','Image (*.tif, *.jpg, *.jpeg)'},'Select an image');
            imshow(image,'Parent',app.UIAxes_3);    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.UploadImageButton_2 = uibutton(app.UIFigure, 'push');
            app.UploadImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButton_2Pushed, true);
            app.UploadImageButton_2.Text = 'Upload Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = imageMorphing(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end